classdef SintetizadorDigitalInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SintetizadorDigitalUIFigure  matlab.ui.Figure
        AButton                      matlab.ui.control.Button
        SButton                      matlab.ui.control.Button
        DButton                      matlab.ui.control.Button
        FButton                      matlab.ui.control.Button
        GButton                      matlab.ui.control.Button
        HButton                      matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        YButton                      matlab.ui.control.Button
        TButton                      matlab.ui.control.Button
        EButton                      matlab.ui.control.Button
        WButton                      matlab.ui.control.Button
        FrequenciaVolumePanel        matlab.ui.container.Panel
        VolumeKnobLabel              matlab.ui.control.Label
        VolumeKnob                   matlab.ui.control.Knob
        TempoPanel                   matlab.ui.container.Panel
        DuraoKnobLabel               matlab.ui.control.Label
        DuracaoKnob                  matlab.ui.control.Knob
        AttackLabel                  matlab.ui.control.Label
        AttKnob                      matlab.ui.control.Knob
        ReleaseLabel                 matlab.ui.control.Label
        RelKnob                      matlab.ui.control.Knob
        VibratoPanel                 matlab.ui.container.Panel
        IntensidadeKnob_2Label       matlab.ui.control.Label
        IntensidadeVKnob             matlab.ui.control.Knob
        VelocidadeKnobLabel          matlab.ui.control.Label
        VelocidadeVKnob              matlab.ui.control.Knob
        TremoloPanel                 matlab.ui.container.Panel
        IntensidadeKnob_3Label       matlab.ui.control.Label
        IntensidadeTKnob             matlab.ui.control.Knob
        VelocidadeKnob_2Label        matlab.ui.control.Label
        VelocidadeTKnob              matlab.ui.control.Knob
        OffsetLabel                  matlab.ui.control.Label
        OffsetTKnob                  matlab.ui.control.Knob
        EscalatorPanel               matlab.ui.container.Panel
        IntensidadeKnob_3Label_2     matlab.ui.control.Label
        IntensidadeEKnob             matlab.ui.control.Knob
        VelocidadeKnob_2Label_2      matlab.ui.control.Label
        VelocidadeEKnob              matlab.ui.control.Knob
        CrescendoDiminuendoPanel     matlab.ui.container.Panel
        CrescendoLabel               matlab.ui.control.Label
        CrescendoKnob                matlab.ui.control.Knob
        DiminuendoLabel              matlab.ui.control.Label
        DiminuendoKnob               matlab.ui.control.Knob
        SlidePanel                   matlab.ui.container.Panel
        SlideUpLabel                 matlab.ui.control.Label
        SlideUpSlider                matlab.ui.control.Slider
        SlideDownSliderLabel         matlab.ui.control.Label
        SlideDownSlider              matlab.ui.control.Slider
        JButton                      matlab.ui.control.Button
        KButton                      matlab.ui.control.Button
        LButton                      matlab.ui.control.Button
        CdButton                     matlab.ui.control.Button
        RightBracketButton           matlab.ui.control.Button
        PButton                      matlab.ui.control.Button
        OButton                      matlab.ui.control.Button
        UButton                      matlab.ui.control.Button
        ValoresPanel                 matlab.ui.container.Panel
        PitchLabel                   matlab.ui.control.Label
        LabelPitch                   matlab.ui.control.Label
        VolumeLabel                  matlab.ui.control.Label
        LabelVolume                  matlab.ui.control.Label
        AttackLabelHint              matlab.ui.control.Label
        AttackLabelValue             matlab.ui.control.Label
        ReleaseLabelHint             matlab.ui.control.Label
        ReleaseLabelValue            matlab.ui.control.Label
        SustainLabelHint             matlab.ui.control.Label
        SustainLabelValue            matlab.ui.control.Label
        DuracaoLabelHint             matlab.ui.control.Label
        DuracaoLabelValue            matlab.ui.control.Label
        RudoPanel                    matlab.ui.container.Panel
        NveldeRudoLabel              matlab.ui.control.Label
        LevelNKnob                   matlab.ui.control.Knob
        Panel                        matlab.ui.container.Panel
        TipodeOndaDropDownLabel      matlab.ui.control.Label
        TipodeOndaDropDown           matlab.ui.control.DropDown
        Harmnico1KnobLabel           matlab.ui.control.Label
        Harmo1Knob                   matlab.ui.control.Knob
        Harmnico2KnobLabel           matlab.ui.control.Label
        Harmo2Knob                   matlab.ui.control.Knob
        Harmnico3KnobLabel           matlab.ui.control.Label
        Harmo3Knob                   matlab.ui.control.Knob
        Harmnico4KnobLabel           matlab.ui.control.Label
        Harmo4Knob                   matlab.ui.control.Knob
        Harmnico5KnobLabel           matlab.ui.control.Label
        Harmo5Knob                   matlab.ui.control.Knob
        Harmnico6KnobLabel           matlab.ui.control.Label
        Harmo6Knob                   matlab.ui.control.Knob
        Harmnico7KnobLabel           matlab.ui.control.Label
        Harmo7Knob                   matlab.ui.control.Knob
        Harmnico8KnobLabel           matlab.ui.control.Label
        Harmo8Knob                   matlab.ui.control.Knob
        Harmnico9KnobLabel           matlab.ui.control.Label
        Harmo9Knob                   matlab.ui.control.Knob
        Harmnico10KnobLabel          matlab.ui.control.Label
        Harmo10Knob                  matlab.ui.control.Knob
        ReiniciarHarmnicosButton     matlab.ui.control.Button
        FrequenciaVolumePanel_2      matlab.ui.container.Panel
        PitchSliderLabel             matlab.ui.control.Label
        PitchSlider                  matlab.ui.control.Slider
    end

    
    properties (Access = private)
        %CONSTANTES
        A4 = 440 %Hz

        %Valores Padrão
        taxa = 44100 %mínima
        resolucao = 24 %mínima
        
        escalator

        %Inputs:
        pitch = 1 %Alteração de Frequência
        duracao = 0.4 %Um tempo de compasso 4/4 à 128 BPM
        speed = 1 %Alteração de Velocidade
        volume = 1
        duracaoInit = 0
        
        frequencia
        
        %Escala de Temperamento Igual no Teclado Musical Padrão( A4 =n°49 )
        altura
        escala
        
        %Síntese Aditiva: Timbre Base
        %Inputs:
        typeWave = 1
        fund
        harmo
        t
        
        %Efeito: Vibrato %Input no Timbre
        %Inputs:
        intensidadeV = 0 %0.01; %variacao
        velocidadeV = 0 %5; %frequencia
        vibrato
        
        %Vibrato:
        variacaoF
        
        %Timbre:
        timbre
        
        %Síntese Subtrativa: Envelope ASR
        %Inputs
        ATT = 0.2
        REL = 0.2
        att
        sus
        rel
        
        %Envelope( ASR ): !Verificar Intervalos!
        A
        S
        R
    
        %e = exp(1);
        fA
        fS
        fR

        envelope
        
        dinamica
        nota
        
        %Síntese por Modulação de Frequência/Fase: Distorção
        %Inputs
        indice = 1 %deslocamento
        carrier = 10 %frequência
        
        %Distorção: 
        %nota = sin((2*pi*carrier)*t+(indice*nota));
  
        %Ruídos
        %Inputs 
        ruido
        levelN = 0
        
        %Efeito: Slide
        %Inputs:
        variacaoSU = 0; %frequencia subindo
        variacaoSD = 0; %frequencia descendo
        slideUp
        slideDn
        
        %Dinâmica?( Teclado de computador não costuma ter sensibilidade de pressão. )
        %Efeito: Tremolo( Modulação AM )
        %Inputs:
        offseT = 0; %eixo de oscilaÃ§Ã£o
        intensidadeT = 0; %volume
        velocidadeT = 0; %frequÃªncia
        
        %Efeito: Escalator
        %Inputs:
        intensidadeE = 0; %variacao
        velocidadeE = 0; %frequencia
        
        %Tremolo:
        variacaoT
        %nota = nota.*variacaoT;
        tremo
        
        %Efeito: Crescendo/Diminuendo
        %Inputs:
        variacaoC = 0; %volume subindo
        variacaoD = 0; %volume descendo
      
        %Crescendo:
        cres
        %Diminuendo:
        dim
    end
    
    methods (Access = private)
        
        function Escalas(app)
            app.altura = @(tecla) app.A4.*(2).^((tecla-49)/12);
            app.escala = app.altura(1:88);                
        end
        
        function SinteseAditiva(app,type)
%             app.fund = app.escala(numEscala)*app.pitch; %A4
            switch(string(type))
                case '1'
                    app.harmo = [1 0 0 0 0 0 0 0 0 0]; %senóide
                case '2'
                    app.harmo = [1 0 1/3 0 1/5 0 1/7 0 1/9 0]; %onda quadrada
                case '3'    
                    app.harmo = [1 0 1/9 0 1/25 0 1/49 0 1/81 0]; %onda triangular
                case '4'    
                    app.harmo = [1 1/2 1/3 1/4 1/5 1/6 1/7 1/8 1/9 1/10]; %sawtooth
                case '5'    
                    app.harmo = [1 0 0.77 0.18 0.11 0.05 0.02 0 0 0 ]; %flute
                case '6'    
                    app.harmo = [1 0.5 0.45 0.5 0.6 0.55 0.65 0.55 0.5 0.45]; %violin
                case '7'    
                    app.harmo = [1 0.0005 0.1 0.0002 0.0009 0.0006 0 0 0 0]; %tests
                case '8'    
                    app.harmo = [app.Harmo1Knob.Value/100 app.Harmo2Knob.Value/100 app.Harmo3Knob.Value/100 app.Harmo4Knob.Value/100 app.Harmo5Knob.Value/100 app.Harmo6Knob.Value/100 app.Harmo7Knob.Value/100 app.Harmo8Knob.Value/100 app.Harmo9Knob.Value/100 app.Harmo10Knob.Value/100]; %tests
            end
%             app.timbre = @(fund)(app.harmo(1)*sin((2*pi*fund).*(app.t+app.vibrato)) + app.harmo(2)*sin((4*pi*fund).*(app.t+app.vibrato)) + app.harmo(3)*sin((6*pi*fund).*(app.t+app.vibrato)) + app.harmo(4)*sin((8*pi*fund).*(app.t+app.vibrato)) + app.harmo(5)*sin((10*pi*app.fund).*(app.t+app.vibrato)) + app.harmo(6)*sin((12*pi*fund).*(app.t+app.vibrato)) + app.harmo(7)*sin((14*pi*fund).*(app.t+app.vibrato)) + app.harmo(8)*sin((16*pi*fund).*(app.t+app.vibrato)) + app.harmo(9)*sin((18*pi*fund).*(app.t+app.vibrato)) + app.harmo(10)*sin((20*pi*fund).*(app.t+app.vibrato)))/sum(app.harmo);
        end
        
        function DefinirTempo(app)
            app.t = app.duracaoInit:1/app.taxa:app.duracao;
        end
        
        function SinteseSubtrativa(app)
            app.att = app.ATT*app.duracao; %Input::ATT            
            app.rel = app.REL*app.duracao; %Input::REL
            app.sus = (1-app.ATT-app.REL)*app.duracao; %Input::SUS
            
            %Envelope( ASR ): !Verificar Intervalos!
            app.A = (0:1/app.taxa:app.att); %intervalo de attack
            app.S = (1/app.taxa:1/app.taxa:app.sus); %intervalo de sustain
            app.R = (1/app.taxa:1/app.taxa:app.rel); %intervalo de release
    
            %e = exp(1);
            app.fA = 1.-exp(1).^((-5/app.att) .*app.A); %5 constantes de tempo
            app.fS = app.S./app.S; %vetor de uns;
            app.fR = exp(1).^((-5/app.rel) .*app.R); %5 constantes de tempo

            app.envelope = [app.fA app.fS app.fR];
%             app.nota = app.volume*app.timbre.*app.fASR;
%             app.dinamica = app.volume*app.envelope .* app.tremo .* app.cres .* app.dim;
%             app.nota = @(n) app.dinamica .*app.timbre(app.frequencia(n));
        end
        
        function SinteseFM(app)
%             app.dinamica = app.volume*app.envelope.*app.tremo.*app.cres.*app.dim;
            
        end
        
        function DefinirRuido(app)
            app.ruido = app.levelN.*randn(1,length(app.t));
%             app.ruido = app.levelRuido*noise(app.taxa*app.duracao,app.channelsRuido,app.typeRuido);
        end
        
        % Efeitos
        
        function Slide(app)            
            %SlideUp:
            app.slideUp = 1.+app.variacaoSU.*app.t;
    
            %SlideDown:
            app.slideDn = 1.-app.variacaoSD.*app.t;
        end
        
        function Vibrato(app)
            %Vibrato:
            app.vibrato = app.intensidadeV.*sin((2*pi.*app.velocidadeV).*app.t);
        end
        
        function Tremolo(app)
            %Tremolo:
            app.tremo = (1-app.offseT/(2*app.volume))+(app.intensidadeT/(2*app.volume))*sin((2*pi*app.velocidadeT).*app.t);
%             app.tremo = app.offseT+app.intensidadeT.*sin((2*pi.*app.velocidadeT).*app.t);
        end
        
        function EfeitoCrescendo(app)
%             app.cres = 1-exp(-0.5756*app.variacaoC*app.t);
            app.cres = 1.+app.variacaoC.*app.t;
        end
        
        function EfeitoDiminuendo(app)
            app.dim = exp(-0.5756*app.variacaoD*app.t);
%             app.dim = 1.-app.variacaoD.*app.t;
        end
        
        function PressKey(app, key)
            center = 49;
            timerStyle = 0.2;
            color = [0.8,0.8,0.8];
            switch(key)
                case 'a'
                    PlaySound(app,center-10)
                    app.AButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.AButton.BackgroundColor = [0.96,0.96,0.96];
                case 'w'
                    PlaySound(app,center-9)
                    app.WButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.WButton.BackgroundColor = [0.96,0.96,0.96];
                case 's'
                    PlaySound(app,center-8)
                    app.SButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.SButton.BackgroundColor = [0.96,0.96,0.96];
                case 'e'
                    PlaySound(app,center-7)
                    app.EButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.EButton.BackgroundColor = [0.96,0.96,0.96];
                case 'd'
                    PlaySound(app,center-6)
                    app.DButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.DButton.BackgroundColor = [0.96,0.96,0.96];
                case 'f'
                    PlaySound(app,center-4)
                    app.FButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.FButton.BackgroundColor = [0.96,0.96,0.96];
                case 't'
                    PlaySound(app,center-3)
                    app.TButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.TButton.BackgroundColor = [0.96,0.96,0.96];
                case 'g'
                    PlaySound(app,center-2)
                    app.GButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.GButton.BackgroundColor = [0.96,0.96,0.96];
                case 'y'
                    PlaySound(app,center-1)
                    app.YButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.YButton.BackgroundColor = [0.96,0.96,0.96];
                case 'h'
                    PlaySound(app,center)
                    app.HButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.HButton.BackgroundColor = [0.96,0.96,0.96];
                case 'u'
                    PlaySound(app,center+1)
                    app.UButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.UButton.BackgroundColor = [0.96,0.96,0.96];
                case 'j'
                    PlaySound(app,center+2)
                    app.JButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.JButton.BackgroundColor = [0.96,0.96,0.96];
                case 'k'
                    PlaySound(app,center+4)
                    app.KButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.KButton.BackgroundColor = [0.96,0.96,0.96];
                case 'o'
                    PlaySound(app,center+5)
                    app.OButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.OButton.BackgroundColor = [0.96,0.96,0.96];
                case 'l'
                    PlaySound(app,center+6)
                    app.LButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.LButton.BackgroundColor = [0.96,0.96,0.96];
                case 'p'
                    PlaySound(app,center+7)
                    app.PButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.PButton.BackgroundColor = [0.96,0.96,0.96];
                case 'semicolon'
                    PlaySound(app,center+8)
                    app.CdButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.CdButton.BackgroundColor = [0.96,0.96,0.96];
                case 'quote'
                    PlaySound(app,center+9)
                    app.RightBracketButton.BackgroundColor = color;
                    pause(timerStyle);
                    app.RightBracketButton.BackgroundColor = [0.96,0.96,0.96];
                otherwise
                    disp(key);
            end
        end
        
        function ParameterUpdate(app,typeWave)
            try
                app.duracaoInit = 0;
                DefinirTempo(app)
                Escalas(app)
                
                Efeitos(app)            
                
                SinteseAditiva(app,typeWave)
                SinteseSubtrativa(app)
                SinteseFM(app)
                
                DefinirRuido(app);
                
                app.dinamica = app.volume*app.envelope.*app.tremo.*app.cres.*app.dim;
            catch
                app.duracaoInit = 1/app.taxa;
                DefinirTempo(app)
                Escalas(app)
                
                Efeitos(app)            
                
                SinteseAditiva(app,typeWave)
                SinteseSubtrativa(app)
                SinteseFM(app)
                
                DefinirRuido(app);
                
                app.dinamica = app.volume*app.envelope.*app.tremo.*app.cres.*app.dim;
            end
            
%             app.nota = @(n) app.dinamica.*app.timbre(app.frequencia(n));
            
%             DefinirRuido(app)
%             plot(app.UIAxes,app.t,app.nota);
            
%             hold(app.UIAxes)
%             refreshdata(app.UIAxes)
%             cla(pax)
%             cla(app.UIAxes)
%             subplot(1,1,1,'Parent',app.UIAxes);
%             plot(app.UIAxes,app.t,app.nota);
            Nota(app,49)
            plot(app.UIAxes,app.t,app.nota);
%             drawnow
            clear sound;
            
%             uicontrol();
        end
        
        function PlaySound(app,valueNota)
            Nota(app, valueNota);
            sound(app.nota,app.taxa,app.resolucao)
        end
        
        function Efeitos(app)
            Slide(app)
            Vibrato(app)
            Escalator(app)
            DefinirFrequencia(app,49)
            EfeitoCrescendo(app)
            EfeitoDiminuendo(app)
            
            Tremolo(app)
        end
        
        function Timbre(app, fund)
%             app.harmo
%             app.vibrato
%             app.t
%             fund
            timbreTemp = app.harmo(1)*sin((2*pi*fund).*(app.t+app.vibrato)) + app.harmo(2)*sin((4*pi*fund).*(app.t+app.vibrato)) + app.harmo(3)*sin((6*pi*fund).*(app.t+app.vibrato)) + app.harmo(4)*sin((8*pi*fund).*(app.t+app.vibrato)) + app.harmo(5)*sin((10*pi*fund).*(app.t+app.vibrato)) + app.harmo(6)*sin((12*pi*fund).*(app.t+app.vibrato)) + app.harmo(7)*sin((14*pi*fund).*(app.t+app.vibrato)) + app.harmo(8)*sin((16*pi*fund).*(app.t+app.vibrato)) + app.harmo(9)*sin((18*pi*fund).*(app.t+app.vibrato)) + app.harmo(10)*sin((20*pi*fund).*(app.t+app.vibrato));
%             app.timbre = app.harmo(1)*sin((2*pi*fund)*(app.t+app.vibrato)) + app.harmo(2).*sin((4*pi*fund).*(app.t+app.vibrato)) + app.harmo(3).*sin((6*pi*fund).*(app.t+app.vibrato)) + app.harmo(4).*sin((8*pi*fund).*(app.t+app.vibrato)) + app.harmo(5).*sin((10*pi*app.fund).*(app.t+app.vibrato)) + app.harmo(6).*sin((12*pi*fund).*(app.t+app.vibrato)) + app.harmo(7).*sin((14*pi*fund).*(app.t+app.vibrato)) + app.harmo(8).*sin((16*pi*fund).*(app.t+app.vibrato)) + app.harmo(9).*sin((18*pi*fund).*(app.t+app.vibrato)) + app.harmo(10).*sin((20*pi*fund).*(app.t+app.vibrato));
            app.timbre = timbreTemp;
            app.timbre = app.timbre/sum(app.harmo);
        end
        
        function Nota(app,valueNota)
            DefinirFrequencia(app,valueNota);
            Timbre(app, app.frequencia);
            app.nota = app.dinamica.*app.timbre + app.ruido;
        end
        
        function Escalator(app)
            app.escalator = 1+app.intensidadeE*sin((2*pi*app.velocidadeE).*app.t);            
        end
        
        function DefinirFrequencia(app,valueNota)
            app.frequencia = app.escala(valueNota).*app.pitch.*app.slideUp.*app.slideDn.*app.escalator;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AttackLabelValue.Text = string(app.ATT);
            app.ReleaseLabelValue.Text = string(app.REL);
            app.SustainLabelValue.Text = string(1-app.ATT-app.REL);
            app.DuracaoLabelValue.Text = string(app.duracao);
            ParameterUpdate(app,app.typeWave)
%             plot(app.UIAxes,app.t,app.nota);
        end

        % Callback function
        function Teste(app, event)
            disp('Botão de Teste Meio')
        end

        % Window key press function: SintetizadorDigitalUIFigure
        function SintetizadorDigitalUIFigureKeyPress(app, event)
            key = event.Key;
            disp(event.Key);
            PressKey(app, key);            
        end

        % Window key release function: SintetizadorDigitalUIFigure
        function SintetizadorDigitalUIFigureKeyRelease(app, event)
            key = event.Key;
            if(key == 'a')
                disp('')
            end
        end

        % Callback function
        function PitchKnobValueChanging(app, event)
            changingValue = event.Value;
            fprintf('Novo valor Knob: %d \n', changingValue);
        end

        % Close request function: SintetizadorDigitalUIFigure
        function SintetizadorDigitalUIFigureCloseRequest(app, event)
            delete(app)
            clear sound
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            PressKey(app,'a');
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            PressKey(app,'s');
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            PressKey(app,'d');
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            PressKey(app,'f');
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            PressKey(app,'g');
        end

        % Button pushed function: HButton
        function HButtonPushed(app, event)
            PressKey(app,'h');
        end

        % Value changed function: TipodeOndaDropDown
        function TipodeOndaDropDownValueChanged(app, event)
            value = app.TipodeOndaDropDown.Value;
            app.typeWave = value;
            ParameterUpdate(app,app.typeWave)
            app.Harmo1Knob.Value = app.harmo(1)*100;
            app.Harmo2Knob.Value = app.harmo(2)*100;
            app.Harmo3Knob.Value = app.harmo(3)*100;
            app.Harmo4Knob.Value = app.harmo(4)*100;
            app.Harmo5Knob.Value = app.harmo(5)*100;
            app.Harmo6Knob.Value = app.harmo(6)*100;
            app.Harmo7Knob.Value = app.harmo(7)*100;
            app.Harmo8Knob.Value = app.harmo(8)*100;
            app.Harmo9Knob.Value = app.harmo(9)*100;
            app.Harmo10Knob.Value = app.harmo(10)*100;
        end

        % Callback function
        function PitchKnobValueChanged(app, event)
            value = app.PitchKnob.Value;
            valueP = ( (2*sqrt(12)) - (1/(2*sqrt(12))) ) * value / 100 + (1/(2*sqrt(12)));
            app.pitch = valueP;
            app.LabelPitch.Text = string(valueP);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
            value = app.VolumeKnob.Value;
            valueLog = (9*value/100) + 1;
            app.volume = log10(valueLog); % [0/100 = 0] até [100/100 = 1]
%             factorE = 1.01;
%             valueLog = (1*(factorE^value)/(factorE^100)) - (1/(factorE^100));
%             if(valueLog > 0.9999)
%                 valueLog = 1;
%             end
%             app.volume = valueLog; % [0/100 = 0] até [100/100 = 1]
            app.LabelVolume.Text = string(app.volume);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: DuracaoKnob
        function DuracaoKnobValueChanged(app, event)
            value = app.DuracaoKnob.Value;
            value = ceil(value/2.5)/10;
            if(value > 0.4)
               app.duracao = value; % [0] até [100/25 = 4]
            else
               app.duracao = 0.4; % [0.4] até [100/25 = 4]
            end
            app.DuracaoLabelValue.Text = string(app.duracao);
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: IntensidadeVKnob
        function IntensidadeVKnobValueChanged(app, event)
            value = app.IntensidadeVKnob.Value;
            app.intensidadeV = value/1000; % [0/1000 = 0] até [100/1000 = 0.1]
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: VelocidadeVKnob
        function VelocidadeVKnobValueChanged(app, event)
            value = app.VelocidadeVKnob.Value;
            app.velocidadeV = value/4; % [0/4 = 0] até [100/4 = 25]
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: IntensidadeTKnob
        function IntensidadeTKnobValueChanged(app, event)
            value = app.IntensidadeTKnob.Value;
            app.intensidadeT = value/100; % [0/100 = 0] até [100/100 = 1]
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: VelocidadeTKnob
        function VelocidadeTKnobValueChanged(app, event)
            value = app.VelocidadeTKnob.Value;
            value = value/10;
%             valueLog = (99*value/100) + 1;
%             app.velocidadeT = log10(valueLog); % [0/1 = 0] até [100/1 = 100]
            app.velocidadeT = value; % [0/1 = 0] até [100/1 = 100]
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: IntensidadeEKnob
        function IntensidadeEKnobValueChanged(app, event)
            value = app.IntensidadeEKnob.Value;
            app.intensidadeE = value/1000; % [0/1000 = 0] até [100/1000 = 0.1]
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: VelocidadeEKnob
        function VelocidadeEKnobValueChanged(app, event)
            value = app.VelocidadeEKnob.Value;
            app.velocidadeE = value; % [0/1 = 0] até [100/1 = 100]
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: SlideUpSlider
        function SlideUpSliderValueChanged(app, event)
            value = app.SlideUpSlider.Value;
            app.variacaoSU = value/20; % [0/20 = 0] até [100/20 = 5]
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: SlideDownSlider
        function SlideDownSliderValueChanged(app, event)
            value = app.SlideDownSlider.Value;
            app.variacaoSD = value/20; % [0/20 = 0] até [100/20 = 5]
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: CrescendoKnob
        function CrescendoKnobValueChanged(app, event)
            value = app.CrescendoKnob.Value;
            if(value < 0.1)
                app.variacaoC = 0.1; % Definindo de [0.1] até [5]
            else
                app.variacaoC = value/20; % [0/20 = 0] até [100/20 = 5]
            end
            
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: DiminuendoKnob
        function DiminuendoKnobValueChanged(app, event)
            value = app.DiminuendoKnob.Value;
            if(value < 0.1)
                app.variacaoD = 0.1; % Definindo de [0.1] até [5]
            else
                app.variacaoD = value/20; % [0/20 = 0] até [100/20 = 5]
            end
%             app.LabelPitch.Text = string(value/100);
            ParameterUpdate(app,app.typeWave)
        end

        % Button pushed function: JButton
        function JButtonPushed(app, event)
            PressKey(app,'j');
        end

        % Button pushed function: KButton
        function KButtonPushed(app, event)
            PressKey(app,'k');
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            PressKey(app,'l');
        end

        % Button pushed function: CdButton
        function CdButtonPushed(app, event)
            PressKey(app,'ç');
        end

        % Button pushed function: RightBracketButton
        function RightBracketButtonPushed(app, event)
            PressKey(app,'rightbracket');
        end

        % Callback function
        function Col2ButtonPushed(app, event)
            PressKey(app,'rightbracket');
        end

        % Callback function
        function Col1ButtonPushed(app, event)
            PressKey(app,'leftbracket');
        end

        % Callback function
        function LeftBracketButtonPushed(app, event)
            PressKey(app,'leftbracket');
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            PressKey(app,'p');
        end

        % Button pushed function: OButton
        function OButtonPushed(app, event)
            PressKey(app,'o');
        end

        % Callback function
        function IButtonPushed(app, event)
            PressKey(app,'i');
        end

        % Button pushed function: UButton
        function UButtonPushed(app, event)
            PressKey(app,'u');
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            PressKey(app,'y');
        end

        % Button pushed function: TButton
        function TButtonPushed(app, event)
            PressKey(app,'t');
        end

        % Callback function
        function RButtonPushed(app, event)
            PressKey(app,'r');
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            PressKey(app,'e');
        end

        % Button pushed function: WButton
        function WButtonPushed(app, event)
            PressKey(app,'w');
        end

        % Value changed function: AttKnob
        function AttKnobValueChanged(app, event)
            value = app.AttKnob.Value;
            value = round(value/5)*5/100;
            valueAttDisp = (1-app.REL);
            if(valueAttDisp>value)
                app.ATT = value; % [0] até [100/100 = 1]
            else
                app.ATT = valueAttDisp; % [0] até [100/100 = 1]
                app.AttKnob.Value = valueAttDisp*100;
                app.RelKnob.Value = app.REL*100;
            end
            app.AttackLabelValue.Text = string(app.ATT);
            app.SustainLabelValue.Text = string(1-app.ATT-app.REL);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: RelKnob
        function RelKnobValueChanged(app, event)
            value = app.RelKnob.Value;
            value = round(value/5)*5/100;
            valueRelDisp = (1-app.ATT);
            if(valueRelDisp>value)
                app.REL = value; % [0] até [100/100 = 1]
            else
                app.REL = valueRelDisp; % [0] até [100/100 = 1]
                app.RelKnob.Value = valueRelDisp*100;
                app.AttKnob.Value = app.ATT*100;
            end
            app.ReleaseLabelValue.Text = string(app.REL);
            app.SustainLabelValue.Text = string(1-app.ATT-app.REL);
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: LevelNKnob
        function LevelNKnobValueChanged(app, event)
            value = app.LevelNKnob.Value;
            app.levelN = value/1000; % [0/1000 = 0] até [100/1000 = 0.1]
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: OffsetTKnob
        function OffsetTKnobValueChanged(app, event)
            value = app.OffsetTKnob.Value;
            app.offseT = value/100; % [0/1000 = 0] até [100/100 = 1]
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: Harmo10Knob, Harmo1Knob, 
        % Harmo2Knob, Harmo3Knob, Harmo4Knob, Harmo5Knob, 
        % Harmo6Knob, Harmo7Knob, Harmo8Knob, Harmo9Knob
        function Harmo1KnobValueChanged(app, event)
%             value = app.Harmo1Knob.Value;
            ParameterUpdate(app,app.typeWave)
        end

        % Button pushed function: ReiniciarHarmnicosButton
        function ReiniciarHarmnicosButtonPushed(app, event)
            app.Harmo1Knob.Value = 100;
            app.Harmo2Knob.Value = 0;
            app.Harmo3Knob.Value = 0;
            app.Harmo4Knob.Value = 0;
            app.Harmo5Knob.Value = 0;
            app.Harmo6Knob.Value = 0;
            app.Harmo7Knob.Value = 0;
            app.Harmo8Knob.Value = 0;
            app.Harmo9Knob.Value = 0;
            app.Harmo10Knob.Value = 0;
            ParameterUpdate(app,app.typeWave)
        end

        % Value changed function: PitchSlider
        function PitchSliderValueChanged(app, event)
            value = app.PitchSlider.Value;
%             valueP = ( (2*nthroot(2, 12)) - (1/2*nthroot(2, 12)) ) * value + (1/2*nthroot(2, 12));
            valueP = (2.1189)^((2)*value/100-1);
            if(valueP<0.5297)
                valueP = 0.5297;
            end
            valueP = round(valueP*1000)/1000;
            app.pitch = valueP;
            app.LabelPitch.Text = string(valueP);
            ParameterUpdate(app,app.typeWave)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SintetizadorDigitalUIFigure = uifigure('Visible', 'off');
            app.SintetizadorDigitalUIFigure.Name = 'Sintetizador Digital';
            app.SintetizadorDigitalUIFigure.CloseRequestFcn = createCallbackFcn(app, @SintetizadorDigitalUIFigureCloseRequest, true);
            app.SintetizadorDigitalUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @SintetizadorDigitalUIFigureKeyPress, true);
            app.SintetizadorDigitalUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @SintetizadorDigitalUIFigureKeyRelease, true);
            app.AButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.SButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.DButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.FButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.GButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.HButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.HButton.ButtonPushedFcn = createCallbackFcn(app, @HButtonPushed, true);
            app.HButton.Text = 'H';
            app.UIAxes = uiaxes(app.SintetizadorDigitalUIFigure);
            title(app.UIAxes, 'Sinal')
            xlabel(app.UIAxes, 'Tempo')
            ylabel(app.UIAxes, 'Amplitude')
            app.YButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y';
            app.TButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @TButtonPushed, true);
            app.TButton.Text = 'T';
            app.EButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.WButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.WButton.Text = 'W';
            app.FrequenciaVolumePanel = uipanel(app.SintetizadorDigitalUIFigure);
            app.FrequenciaVolumePanel.Title = 'Volume';
            app.VolumeKnobLabel = uilabel(app.FrequenciaVolumePanel);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.FrequenciaVolumePanel, 'continuous');
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            app.VolumeKnob.Value = 100;
            app.TempoPanel = uipanel(app.SintetizadorDigitalUIFigure);
            app.TempoPanel.Title = 'Tempo';
            app.DuraoKnobLabel = uilabel(app.TempoPanel);
            app.DuraoKnobLabel.Text = 'Duração';
            app.DuracaoKnob = uiknob(app.TempoPanel, 'continuous');
            app.DuracaoKnob.ValueChangedFcn = createCallbackFcn(app, @DuracaoKnobValueChanged, true);
            app.DuracaoKnob.Value = 10;
            app.AttackLabel = uilabel(app.TempoPanel);
            app.AttackLabel.Text = 'Attack';
            app.AttKnob = uiknob(app.TempoPanel, 'continuous');
            app.AttKnob.ValueChangedFcn = createCallbackFcn(app, @AttKnobValueChanged, true);
            app.AttKnob.Value = 20;
            app.ReleaseLabel = uilabel(app.TempoPanel);
            app.ReleaseLabel.Text = 'Release';
            app.RelKnob = uiknob(app.TempoPanel, 'continuous');
            app.RelKnob.ValueChangedFcn = createCallbackFcn(app, @RelKnobValueChanged, true);
            app.RelKnob.Value = 20;
            app.VibratoPanel = uipanel(app.SintetizadorDigitalUIFigure);
            app.VibratoPanel.Title = 'Vibrato';
            app.IntensidadeKnob_2Label = uilabel(app.VibratoPanel);
            app.IntensidadeKnob_2Label.Text = 'Intensidade';
            app.IntensidadeVKnob = uiknob(app.VibratoPanel, 'continuous');
            app.IntensidadeVKnob.ValueChangedFcn = createCallbackFcn(app, @IntensidadeVKnobValueChanged, true);
            app.VelocidadeKnobLabel = uilabel(app.VibratoPanel);
            app.VelocidadeKnobLabel.Text = 'Velocidade';
            app.VelocidadeVKnob = uiknob(app.VibratoPanel, 'continuous');
            app.VelocidadeVKnob.ValueChangedFcn = createCallbackFcn(app, @VelocidadeVKnobValueChanged, true);
            app.TremoloPanel = uipanel(app.SintetizadorDigitalUIFigure);
            app.TremoloPanel.Title = 'Tremolo';
            app.IntensidadeKnob_3Label = uilabel(app.TremoloPanel);
            app.IntensidadeKnob_3Label.Text = 'Intensidade';
            app.IntensidadeTKnob = uiknob(app.TremoloPanel, 'continuous');
            app.IntensidadeTKnob.ValueChangedFcn = createCallbackFcn(app, @IntensidadeTKnobValueChanged, true);
            app.VelocidadeKnob_2Label = uilabel(app.TremoloPanel);
            app.VelocidadeKnob_2Label.Text = 'Velocidade';
            app.VelocidadeTKnob = uiknob(app.TremoloPanel, 'continuous');
            app.VelocidadeTKnob.ValueChangedFcn = createCallbackFcn(app, @VelocidadeTKnobValueChanged, true);
            app.OffsetLabel = uilabel(app.TremoloPanel);
            app.OffsetLabel.Text = 'Offset';
            app.OffsetTKnob = uiknob(app.TremoloPanel, 'continuous');
            app.OffsetTKnob.ValueChangedFcn = createCallbackFcn(app, @OffsetTKnobValueChanged, true);
            app.EscalatorPanel = uipanel(app.SintetizadorDigitalUIFigure);
            app.EscalatorPanel.Title = 'Escalator';
            app.IntensidadeKnob_3Label_2 = uilabel(app.EscalatorPanel);
            app.IntensidadeKnob_3Label_2.Text = 'Intensidade';
            app.IntensidadeEKnob = uiknob(app.EscalatorPanel, 'continuous');
            app.IntensidadeEKnob.ValueChangedFcn = createCallbackFcn(app, @IntensidadeEKnobValueChanged, true);
            app.VelocidadeKnob_2Label_2 = uilabel(app.EscalatorPanel);
            app.VelocidadeKnob_2Label_2.Text = 'Velocidade';
            app.VelocidadeEKnob = uiknob(app.EscalatorPanel, 'continuous');
            app.VelocidadeEKnob.ValueChangedFcn = createCallbackFcn(app, @VelocidadeEKnobValueChanged, true);
            app.CrescendoDiminuendoPanel = uipanel(app.SintetizadorDigitalUIFigure);
            app.CrescendoDiminuendoPanel.Title = 'Crescendo/Diminuendo';
            app.CrescendoLabel = uilabel(app.CrescendoDiminuendoPanel);
            app.CrescendoLabel.Text = 'Crescendo';
            app.CrescendoKnob = uiknob(app.CrescendoDiminuendoPanel, 'continuous');
            app.CrescendoKnob.ValueChangedFcn = createCallbackFcn(app, @CrescendoKnobValueChanged, true);
            app.DiminuendoLabel = uilabel(app.CrescendoDiminuendoPanel);
            app.DiminuendoLabel.Text = 'Diminuendo';
            app.DiminuendoKnob = uiknob(app.CrescendoDiminuendoPanel, 'continuous');
            app.DiminuendoKnob.ValueChangedFcn = createCallbackFcn(app, @DiminuendoKnobValueChanged, true);
            app.SlidePanel = uipanel(app.SintetizadorDigitalUIFigure);
            app.SlidePanel.Title = 'Slide';
            app.SlideUpLabel = uilabel(app.SlidePanel);
            app.SlideUpLabel.Text = {'Slide'; 'Up'};
            app.SlideUpSlider = uislider(app.SlidePanel);
            app.SlideUpSlider.ValueChangedFcn = createCallbackFcn(app, @SlideUpSliderValueChanged, true);
            app.SlideDownSliderLabel = uilabel(app.SlidePanel);
            app.SlideDownSliderLabel.Text = {'Slide'; 'Down'};
            app.SlideDownSlider = uislider(app.SlidePanel);
            app.SlideDownSlider.ValueChangedFcn = createCallbackFcn(app, @SlideDownSliderValueChanged, true);
            app.JButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.JButton.ButtonPushedFcn = createCallbackFcn(app, @JButtonPushed, true);
            app.JButton.Text = 'J';
            app.KButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.KButton.ButtonPushedFcn = createCallbackFcn(app, @KButtonPushed, true);
            app.KButton.Text = 'K';
            app.LButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.CdButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.CdButton.ButtonPushedFcn = createCallbackFcn(app, @CdButtonPushed, true);
            app.CdButton.Text = 'Ç';
            app.RightBracketButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.RightBracketButton.ButtonPushedFcn = createCallbackFcn(app, @RightBracketButtonPushed, true);
            app.RightBracketButton.Text = '~';
            app.PButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = 'P';
            app.OButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @OButtonPushed, true);
            app.OButton.Text = 'O';
            app.UButton = uibutton(app.SintetizadorDigitalUIFigure, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Text = 'U';
            app.ValoresPanel = uipanel(app.SintetizadorDigitalUIFigure);
            app.ValoresPanel.Title = 'Valores';
            app.PitchLabel = uilabel(app.ValoresPanel);
            app.PitchLabel.Text = 'Pitch:';
            app.LabelPitch = uilabel(app.ValoresPanel);
            app.LabelPitch.Text = '1';
            app.VolumeLabel = uilabel(app.ValoresPanel);
            app.VolumeLabel.Text = 'Volume';
            app.LabelVolume = uilabel(app.ValoresPanel);
            app.LabelVolume.Text = '1';
            app.AttackLabelHint = uilabel(app.ValoresPanel);
            app.AttackLabelHint.Text = 'Attack';
            app.AttackLabelValue = uilabel(app.ValoresPanel);
            app.AttackLabelValue.Text = '1';
            app.ReleaseLabelHint = uilabel(app.ValoresPanel);
            app.ReleaseLabelHint.Text = 'Release';
            app.ReleaseLabelValue = uilabel(app.ValoresPanel);
            app.ReleaseLabelValue.Text = '1';
            app.SustainLabelHint = uilabel(app.ValoresPanel);
            app.SustainLabelHint.Text = 'Sustain';
            app.SustainLabelValue = uilabel(app.ValoresPanel);
            app.SustainLabelValue.Text = '1';
            app.DuracaoLabelHint = uilabel(app.ValoresPanel);
            app.DuracaoLabelHint.Text = 'Duração';
            app.DuracaoLabelValue = uilabel(app.ValoresPanel);
            app.DuracaoLabelValue.Text = '1';
            app.RudoPanel = uipanel(app.SintetizadorDigitalUIFigure);
            app.RudoPanel.Title = 'Ruído';
            app.NveldeRudoLabel = uilabel(app.RudoPanel);
            app.NveldeRudoLabel.Text = 'Nível de Ruído';
            app.LevelNKnob = uiknob(app.RudoPanel, 'continuous');
            app.LevelNKnob.ValueChangedFcn = createCallbackFcn(app, @LevelNKnobValueChanged, true);
            app.Panel = uipanel(app.SintetizadorDigitalUIFigure);
            app.Panel.Title = 'Panel';
            app.TipodeOndaDropDownLabel = uilabel(app.Panel);
            app.TipodeOndaDropDownLabel.Text = 'Tipo de Onda';
            app.TipodeOndaDropDown = uidropdown(app.Panel);
            app.TipodeOndaDropDown.Items = {'Senóide', 'Onda Quadrada', 'Onda Triangular', 'Dente de Serra', 'Flauta', 'Violino', 'Tests', 'Custom'};
            app.TipodeOndaDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.TipodeOndaDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodeOndaDropDownValueChanged, true);
            app.TipodeOndaDropDown.Value = '1';
            app.Harmnico1KnobLabel = uilabel(app.Panel);
            app.Harmnico1KnobLabel.Text = 'Harmônico 1';
            app.Harmo1Knob = uiknob(app.Panel, 'continuous');
            app.Harmo1Knob.ValueChangedFcn = createCallbackFcn(app, @Harmo1KnobValueChanged, true);
            app.Harmo1Knob.Value = 100;
            app.Harmnico2KnobLabel = uilabel(app.Panel);
            app.Harmnico2KnobLabel.Text = 'Harmônico 2';
            app.Harmo2Knob = uiknob(app.Panel, 'continuous');
            app.Harmo2Knob.ValueChangedFcn = createCallbackFcn(app, @Harmo1KnobValueChanged, true);
            app.Harmnico3KnobLabel = uilabel(app.Panel);
            app.Harmnico3KnobLabel.Text = 'Harmônico 3';
            app.Harmo3Knob = uiknob(app.Panel, 'continuous');
            app.Harmo3Knob.ValueChangedFcn = createCallbackFcn(app, @Harmo1KnobValueChanged, true);
            app.Harmnico4KnobLabel = uilabel(app.Panel);
            app.Harmnico4KnobLabel.Text = 'Harmônico 4';
            app.Harmo4Knob = uiknob(app.Panel, 'continuous');
            app.Harmo4Knob.ValueChangedFcn = createCallbackFcn(app, @Harmo1KnobValueChanged, true);
            app.Harmnico5KnobLabel = uilabel(app.Panel);
            app.Harmnico5KnobLabel.Text = 'Harmônico 5';
            app.Harmo5Knob = uiknob(app.Panel, 'continuous');
            app.Harmo5Knob.ValueChangedFcn = createCallbackFcn(app, @Harmo1KnobValueChanged, true);
            app.Harmnico6KnobLabel = uilabel(app.Panel);
            app.Harmnico6KnobLabel.Text = 'Harmônico 6';
            app.Harmo6Knob = uiknob(app.Panel, 'continuous');
            app.Harmo6Knob.ValueChangedFcn = createCallbackFcn(app, @Harmo1KnobValueChanged, true);
            app.Harmnico7KnobLabel = uilabel(app.Panel);
            app.Harmnico7KnobLabel.Text = 'Harmônico 7';
            app.Harmo7Knob = uiknob(app.Panel, 'continuous');
            app.Harmo7Knob.ValueChangedFcn = createCallbackFcn(app, @Harmo1KnobValueChanged, true);
            app.Harmnico8KnobLabel = uilabel(app.Panel);
            app.Harmnico8KnobLabel.Text = 'Harmônico 8';
            app.Harmo8Knob = uiknob(app.Panel, 'continuous');
            app.Harmo8Knob.ValueChangedFcn = createCallbackFcn(app, @Harmo1KnobValueChanged, true);
            app.Harmnico9KnobLabel = uilabel(app.Panel);
            app.Harmnico9KnobLabel.Text = 'Harmônico 9';
            app.Harmo9Knob = uiknob(app.Panel, 'continuous');
            app.Harmo9Knob.ValueChangedFcn = createCallbackFcn(app, @Harmo1KnobValueChanged, true);
            app.Harmnico10KnobLabel = uilabel(app.Panel);
            app.Harmnico10KnobLabel.Text = 'Harmônico 10';
            app.Harmo10Knob = uiknob(app.Panel, 'continuous');
            app.Harmo10Knob.ValueChangedFcn = createCallbackFcn(app, @Harmo1KnobValueChanged, true);
            app.ReiniciarHarmnicosButton = uibutton(app.Panel, 'push');
            app.ReiniciarHarmnicosButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarHarmnicosButtonPushed, true);
            app.ReiniciarHarmnicosButton.Text = 'Reiniciar Harmônicos';
            app.FrequenciaVolumePanel_2 = uipanel(app.SintetizadorDigitalUIFigure);
            app.FrequenciaVolumePanel_2.Title = 'Frequência';
            app.PitchSliderLabel = uilabel(app.FrequenciaVolumePanel_2);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.FrequenciaVolumePanel_2);
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @PitchSliderValueChanged, true);
            app.PitchSlider.Value = 50;
            % Show the figure after all components are created
            app.SintetizadorDigitalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SintetizadorDigitalInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SintetizadorDigitalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SintetizadorDigitalUIFigure)
        end
    end
end